classdef myMemoryGUIApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        CorrectNTSoundPanel          matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        SoundSwitch                  matlab.ui.control.Switch
        DataLoadPanel                matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        SearchPathButton             matlab.ui.control.Button
        CreateNewDatabaseButton      matlab.ui.control.Button
        DefaultPath                  matlab.ui.control.TextArea
        LoadMAT                      matlab.ui.control.DropDown
        TabGroup                     matlab.ui.container.TabGroup
        LearningTab                  matlab.ui.container.Tab
        GridLayout3                  matlab.ui.container.GridLayout
        SwapModeButton               matlab.ui.control.StateButton
        Gauge                        matlab.ui.control.SemicircularGauge
        LearningStartStopButton      matlab.ui.control.StateButton
        AnswerTextArea               matlab.ui.control.TextArea
        AnswerTextAreaLabel          matlab.ui.control.Label
        QuestionTextArea             matlab.ui.control.TextArea
        QuestionTextAreaLabel        matlab.ui.control.Label
        LearningModeButtonGroup      matlab.ui.container.ButtonGroup
        NumOfData                    matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        LearningtimesEditField       matlab.ui.control.EditField
        LearningtimesEditFieldLabel  matlab.ui.control.Label
        RateofIncorrectButton        matlab.ui.control.RadioButton
        FromOldestButton             matlab.ui.control.RadioButton
        ofIncorrectButton            matlab.ui.control.RadioButton
        ofLearningsButton            matlab.ui.control.RadioButton
        MetricsPanel                 matlab.ui.container.Panel
        GridLayout5                  matlab.ui.container.GridLayout
        MetricsTable                 matlab.ui.control.Table
        NiceTryButton                matlab.ui.control.Button
        CorrectButton                matlab.ui.control.Button
        CheckAnswerButton            matlab.ui.control.Button
        Sentence                     matlab.ui.control.TextArea
        EditDataTab                  matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        DeletearowButton             matlab.ui.control.Button
        DupCheckButton               matlab.ui.control.StateButton
        myMemoryTable                matlab.ui.control.Table
        SaveDataButton               matlab.ui.control.Button
        AddNewButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        myMemoryObj myMemory % Object of myMemory class
        LC = 0 % LearningCount % default
        currentRow = 0 % number of current Row in the table
        
        %% Sound Setting
        correctSound % sound for correct
        NiceTrySound % sound for NiceTry
        SR % Sampling Rate
        soundOnOff = 'Off' % sound on/off
    end
    
    methods (Access = private)
        
        % myMemory Object creation
        function myMemoryRead(app,varargin)
            if nargin >= 2
                app.myMemoryObj = myMemory(varargin{1});
            else
                app.myMemoryObj = myMemory();
                app.myMemoryObj.addWord("","",""); % add blank data
            end
            % Display the word table
            app.refreshDataTable();
        end
        
        % Refresh the data learning table
        function refreshDataTable(app,varargin)
            app.currentRow = 0; % reset the row number
            if nargin > 1
                app.myMemoryTable.Data = app.myMemoryObj.T(varargin{1},["word","meaning","ex"]);
            else
                app.myMemoryTable.Data = app.myMemoryObj.T(:,["word","meaning","ex"]);
            end
            app.NumOfData.Text = num2str(height(app.myMemoryTable.Data));
        end
        
        % Show duplicate items
        function showDupTable(app)
            [~,~,allIdx] = unique(app.myMemoryObj.T(:,"word"));
            occurrences = accumarray(allIdx, 1);
            dupoccurrences = find(occurrences > 1);
            
            if isempty(dupoccurrences)
            % No duplicate
                app.myMemoryTable.Data = [];
            else
                idxAll = find(allIdx == dupoccurrences(1));
                for i=2:length(dupoccurrences)
                    idx = find(allIdx == dupoccurrences(i));
                    idxAll = [idxAll,idx];
                end
                app.refreshDataTable(idxAll);
            end

        end
        
        % Refresh the metrics table data
        function refreshMetricsTable(app)
            if app.LearningStartStopButton.Value
                lDI = app.myMemoryObj.learningDataIndices;
                app.MetricsTable.Data = app.myMemoryObj.T(lDI(app.LC),["nLearn","nWrong","pWrong","regDay"]);
            else
                app.MetricsTable.Data = [];
            end
        end
        
        % Refresh and update the learning in process
        function refreshAndUpdateLearning(app)
            if app.LearningStartStopButton.Value
                % update the Learning count and gauge
                app.LC = app.LC + 1;
                app.Gauge.Value = app.LC/length(app.myMemoryObj.learningDataIndices)*100;

                if ~(app.LC > length(app.myMemoryObj.learningDataIndices))
                    % Read Learning Data Indices
                    lDI = app.myMemoryObj.learningDataIndices(app.LC);
                    % Show new Question and clear other boxes
                    app.QuestionTextArea.Value = app.myMemoryObj.T.word(lDI);
                    app.AnswerTextArea.Value = "";
                    app.Sentence.Value = "";
                    % Show the metrics of the Question
                    app.refreshMetricsTable();
                
                else % Learning finished
                    app.LearningStartStopButton.Value = false;
                    app.LearningStartStopButtonValueChanged();
                end
            else
                return
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % List of available mat files
            app.UIFigure.Name = "Your Memory";
            app.DefaultPath.Value = pwd;
            app.LoadMATDropDownOpening();
            % Load sound
            [CS, SR1] = audioread("Correct.mp3");
            [NTS, ~] = audioread("NiceTry.mp3");
            app.correctSound = CS;
            app.NiceTrySound = NTS;
            app.SR = SR1;
        end

        % Button pushed function: CreateNewDatabaseButton
        function CreateNewDatabaseButtonPushed(app, event)
            % In case there exists a working database on the table
            if app.LoadMAT.Value ~= "Select Your Database"
                app.SaveDataButtonPushed(); % Save the current data
            end
            
            % In case Creating a new database
            app.LoadMAT.Value = "Select Your Database"; % clear database
            myMemoryRead(app);
            app.TabGroup.SelectedTab = app.EditDataTab;
            warndlg("Make sure to hit Save Data button before moving on to Learning!","Warning");
        end

        % Value changed function: LoadMAT
        function LoadMATValueChanged(app, event)
            % MAT File full path
            yourMAT = fullfile(char(app.DefaultPath.Value),app.LoadMAT.Value);
            if (app.LoadMAT.Value=="Select Your Database")
                app.myMemoryTable.Data = [];
                app.LearningStartStopButton.Value = false;
                app.LearningStartStopButtonValueChanged();
                return
            end

            try
                % Load database
                load(yourMAT,"MemoryTable");
                % Register the database to your object
                myMemoryRead(app,MemoryTable);
            catch ME
                errordlg(ME.message);
            end
        end

        % Cell edit callback: myMemoryTable
        function myMemoryTableCellEdit(app, event)
            if ~app.DupCheckButton.Value % update unless dup check is running
                indices = event.Indices;
                newData = event.NewData;
                app.myMemoryObj.T{indices(1),indices(2)} = newData;
            end
        end

        % Value changed function: LearningStartStopButton
        function LearningStartStopButtonValueChanged(app, event)
            % Check if the table data is ready.
            if (isempty(app.myMemoryObj)||app.LoadMAT.Value=="Select Your Database")
                app.LearningStartStopButton.Value = false;
                warndlg("Choose Your Database!","Warning");
            end

            if app.LearningStartStopButton.Value % while it's on
                % numOfQuestion
                numQuestion = str2double(app.LearningtimesEditField.Value);
                % Learning method
                method = "default";
                if  app.RateofIncorrectButton.Value
                    method = "pWrong";
                elseif app.ofIncorrectButton.Value
                    method = "nWrong";
                elseif app.ofLearningsButton.Value
                    method = "nLearn";
                end

                % Learning data set (learningDataIndices)
                app.myMemoryObj.learning(numQuestion,method);               
                % Show Question #1
                app.refreshAndUpdateLearning();
                % Show the metrics of Question #1
                app.refreshMetricsTable();
                % Update the Gauge value
                app.Gauge.Value = 1/numQuestion*100;
            else % Learning interrupted
                % Learning quite
                app.refreshMetricsTable(); % refresh to blank
                app.QuestionTextArea.Value = "";
                app.AnswerTextArea.Value = "";
                app.Sentence.Value = "";
                % Reset Learning Count
                app.LC = 0;
                % Reset the gauge
                app.Gauge.Value = 0;
            end
            
            % Save file
            app.SaveDataButton();
        end

        % Button pushed function: AddNewButton
        function AddNewButtonPushed(app, event)
            if isempty(app.myMemoryObj)
                app.myMemoryRead();
            else
                app.myMemoryObj.addWord("","","");
            end
            % update the word table
            app.refreshDataTable();
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            value = app.LoadMAT.Value;
            % New data:
            if (value=="Select Your Database")
                prompt = {"Enter User Name:"};
                dlgtitle = "Save Database";
                fieldsize = [1 45];
                definput = "usr";
                usrName = string(inputdlg(prompt,dlgtitle,fieldsize,definput));
            else
                usrName = value;
            end
            
            % Save the table
            if isempty(usrName) % if name input is canceled, then close
                return
            else
                app.myMemoryObj.uName = usrName;
                app.myMemoryObj.saveTable(app.DefaultPath.Value);

                % Load the new data
                if (value=="Select Your Database")
                    yourMAT = fullfile(char(app.DefaultPath.Value),usrName);
                    load(yourMAT,"MemoryTable"); % Load the table again
                    myMemoryRead(app,MemoryTable); % Register it with your object
                    app.LoadMATDropDownOpening();
                    app.LoadMAT.Value = {char(usrName + ".mat")};
                end
            end
        end

        % Drop down opening function: LoadMAT
        function LoadMATDropDownOpening(app, event)
            dname = app.DefaultPath.Value;
            ListMat = dir(fullfile(dname,"*.mat"));
            app.LoadMAT.Items = {'Select Your Database',ListMat.name};
        end

        % Button pushed function: CheckAnswerButton
        function CheckAnswerButtonPushed(app, event)
            if app.LearningStartStopButton.Value
                % Index extraction
                lDI = app.myMemoryObj.learningDataIndices(app.LC);
                % Update the display
                app.AnswerTextArea.Value = app.myMemoryObj.T.meaning(lDI);
                app.Sentence.Value = app.myMemoryObj.T.ex(lDI);
                app.refreshMetricsTable();
            else
                return
            end
        end

        % Button pushed function: CorrectButton
        function CorrectButtonPushed(app, event)
            if app.LearningStartStopButton.Value
                % correct sound
                if strcmp(app.soundOnOff,'On')
                    sound(app.correctSound,app.SR);
                end

                lDI = app.myMemoryObj.learningDataIndices(app.LC);
                % Update the metrics
                app.myMemoryObj.T.nLearn(lDI) = app.myMemoryObj.T.nLearn(lDI) + 1;
                app.myMemoryObj.T.pWrong(lDI) = ...
                    single(app.myMemoryObj.T.nWrong(lDI)) / single(app.myMemoryObj.T.nLearn(lDI));
                app.myMemoryObj.T.regDay(lDI) = datetime("today"); % date updated
                % Refresh the Metrics table
                app.refreshMetricsTable();
                % Refresh and update the learning
                app.refreshAndUpdateLearning()
            else
                return
            end
        end

        % Button pushed function: NiceTryButton
        function NiceTryButtonPushed(app, event)
            if app.LearningStartStopButton.Value
                % correct sound
                if strcmp(app.soundOnOff,'On')
                    sound(app.NiceTrySound,app.SR);
                end

                lDI = app.myMemoryObj.learningDataIndices(app.LC);
                % Update the metrics
                app.myMemoryObj.T.nLearn(lDI) = app.myMemoryObj.T.nLearn(lDI) + 1;
                app.myMemoryObj.T.nWrong(lDI) = app.myMemoryObj.T.nWrong(lDI) + 1;
                app.myMemoryObj.T.pWrong(lDI) = ...
                    single(app.myMemoryObj.T.nWrong(lDI)) / single(app.myMemoryObj.T.nLearn(lDI));
                app.myMemoryObj.T.regDay(lDI) = datetime("today"); % date updated
                % Refresh the Metrics table
                app.refreshMetricsTable();
                % Refresh and update the learning
                app.refreshAndUpdateLearning();
            else
                return
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.SwapModeButton.Value
                app.myMemoryObj.swapWordMeaning(); % restore the database
            end
            app.SaveDataButtonPushed();
            delete(app);
        end

        % Cell selection callback: myMemoryTable
        function myMemoryTableCellSelection(app, event)
            if ~isempty(event.Indices)
                app.currentRow = event.Indices(1);
            end
        end

        % Button pushed function: SearchPathButton
        function SearchPathButtonPushed(app, event)
            dname = uigetdir;
            if ~dname
                dname=pwd;
            end
            %cd(dname);
            app.DefaultPath.Value = dname;
            app.LoadMATDropDownOpening();
        end

        % Value changed function: SwapModeButton
        function SwapModeButtonValueChanged(app, event)
            if ~app.LearningStartStopButton.Value
                app.myMemoryObj.swapWordMeaning(); % swap words with meanings
                app.refreshDataTable();
            else
                app.SwapModeButton.Value = ~app.SwapModeButton.Value; % do not change the status
            end
        end

        % Value changed function: DupCheckButton
        function DupCheckButtonValueChanged(app, event)
            value = app.DupCheckButton.Value;
            if value
                app.showDupTable();
                app.DupCheckButton.Text = "Stop Check";
            else
                app.refreshDataTable();
                app.DupCheckButton.Text = "Dup Check";
            end
        end

        % Button pushed function: DeletearowButton
        function DeletearowButtonPushed(app, event)
            if ~app.DupCheckButton.Value && app.currentRow
                % Delete a word
                app.myMemoryObj.deleteWords(app.currentRow);
                % refresh the table
                app.refreshDataTable();
            else
                return
            end
        end

        % Value changed function: SoundSwitch
        function SoundSwitchValueChanged(app, event)
            value = app.SoundSwitch.Value;
            app.soundOnOff = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.LearningTab = uitab(app.TabGroup);
            app.LearningTab.Title = 'Learning';
            app.GridLayout3 = uigridlayout(app.LearningTab);
            app.Sentence = uitextarea(app.GridLayout3);
            app.CheckAnswerButton = uibutton(app.GridLayout3, 'push');
            app.CheckAnswerButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAnswerButtonPushed, true);
            app.CheckAnswerButton.Text = 'Check Answer';
            app.CorrectButton = uibutton(app.GridLayout3, 'push');
            app.CorrectButton.ButtonPushedFcn = createCallbackFcn(app, @CorrectButtonPushed, true);
            app.CorrectButton.Text = 'Correct !';
            app.NiceTryButton = uibutton(app.GridLayout3, 'push');
            app.NiceTryButton.ButtonPushedFcn = createCallbackFcn(app, @NiceTryButtonPushed, true);
            app.NiceTryButton.Text = {'Nice'; 'Try'};
            app.MetricsPanel = uipanel(app.GridLayout3);
            app.MetricsPanel.Title = 'Metrics';
            app.GridLayout5 = uigridlayout(app.MetricsPanel);
            app.MetricsTable = uitable(app.GridLayout5);
            app.MetricsTable.ColumnName = {'# of Learnings'; '# of Incorrect answers '; 'Incorrect answer rate'; 'Last Day of Learning'};
            app.MetricsTable.RowName = {};
            app.LearningModeButtonGroup = uibuttongroup(app.GridLayout3);
            app.LearningModeButtonGroup.Title = 'Learning Mode';
            app.ofLearningsButton = uiradiobutton(app.LearningModeButtonGroup);
            app.ofLearningsButton.Text = '# of Learnings';
            app.ofIncorrectButton = uiradiobutton(app.LearningModeButtonGroup);
            app.ofIncorrectButton.Text = '# of Incorrect';
            app.FromOldestButton = uiradiobutton(app.LearningModeButtonGroup);
            app.FromOldestButton.Text = 'From Oldest';
            app.RateofIncorrectButton = uiradiobutton(app.LearningModeButtonGroup);
            app.RateofIncorrectButton.Text = 'Rate of Incorrect';
            app.RateofIncorrectButton.Value = true;
            app.LearningtimesEditFieldLabel = uilabel(app.LearningModeButtonGroup);
            app.LearningtimesEditFieldLabel.Text = 'Learning times';
            app.LearningtimesEditField = uieditfield(app.LearningModeButtonGroup, 'text');
            app.LearningtimesEditField.Value = '20';
            app.Label = uilabel(app.LearningModeButtonGroup);
            app.Label.Text = '/';
            app.NumOfData = uilabel(app.LearningModeButtonGroup);
            app.NumOfData.Text = '---';
            app.QuestionTextAreaLabel = uilabel(app.GridLayout3);
            app.QuestionTextAreaLabel.Text = 'Question';
            app.QuestionTextArea = uitextarea(app.GridLayout3);
            app.AnswerTextAreaLabel = uilabel(app.GridLayout3);
            app.AnswerTextAreaLabel.Text = 'Answer';
            app.AnswerTextArea = uitextarea(app.GridLayout3);
            app.LearningStartStopButton = uibutton(app.GridLayout3, 'state');
            app.LearningStartStopButton.ValueChangedFcn = createCallbackFcn(app, @LearningStartStopButtonValueChanged, true);
            app.LearningStartStopButton.Text = 'Learning Start / Stop';
            app.Gauge = uigauge(app.GridLayout3, 'semicircular');
            app.SwapModeButton = uibutton(app.GridLayout3, 'state');
            app.SwapModeButton.ValueChangedFcn = createCallbackFcn(app, @SwapModeButtonValueChanged, true);
            app.SwapModeButton.Text = {'Swap'; 'Mode'; '<< >>'};
            app.EditDataTab = uitab(app.TabGroup);
            app.EditDataTab.Title = 'Edit Data';
            app.GridLayout4 = uigridlayout(app.EditDataTab);
            app.AddNewButton = uibutton(app.GridLayout4, 'push');
            app.AddNewButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewButtonPushed, true);
            app.AddNewButton.Text = 'Add New';
            app.SaveDataButton = uibutton(app.GridLayout4, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.myMemoryTable = uitable(app.GridLayout4);
            app.myMemoryTable.ColumnName = {'Question'; 'Answer'; 'Example'};
            app.myMemoryTable.RowName = {};
            app.myMemoryTable.ColumnEditable = [true true true];
            app.myMemoryTable.CellEditCallback = createCallbackFcn(app, @myMemoryTableCellEdit, true);
            app.myMemoryTable.CellSelectionCallback = createCallbackFcn(app, @myMemoryTableCellSelection, true);
            app.DupCheckButton = uibutton(app.GridLayout4, 'state');
            app.DupCheckButton.ValueChangedFcn = createCallbackFcn(app, @DupCheckButtonValueChanged, true);
            app.DupCheckButton.Text = 'Dup Check';
            app.DeletearowButton = uibutton(app.GridLayout4, 'push');
            app.DeletearowButton.ButtonPushedFcn = createCallbackFcn(app, @DeletearowButtonPushed, true);
            app.DeletearowButton.Text = 'Delete a row';
            app.DataLoadPanel = uipanel(app.GridLayout);
            app.DataLoadPanel.Title = 'Data Load';
            app.GridLayout2 = uigridlayout(app.DataLoadPanel);
            app.LoadMAT = uidropdown(app.GridLayout2);
            app.LoadMAT.Items = {'Select Your Database'};
            app.LoadMAT.DropDownOpeningFcn = createCallbackFcn(app, @LoadMATDropDownOpening, true);
            app.LoadMAT.ValueChangedFcn = createCallbackFcn(app, @LoadMATValueChanged, true);
            app.LoadMAT.Value = 'Select Your Database';
            app.DefaultPath = uitextarea(app.GridLayout2);
            app.CreateNewDatabaseButton = uibutton(app.GridLayout2, 'push');
            app.CreateNewDatabaseButton.ButtonPushedFcn = createCallbackFcn(app, @CreateNewDatabaseButtonPushed, true);
            app.CreateNewDatabaseButton.Text = 'Create New Database';
            app.SearchPathButton = uibutton(app.GridLayout2, 'push');
            app.SearchPathButton.ButtonPushedFcn = createCallbackFcn(app, @SearchPathButtonPushed, true);
            app.SearchPathButton.Text = 'Search Path';
            app.CorrectNTSoundPanel = uipanel(app.GridLayout);
            app.CorrectNTSoundPanel.Title = 'Correct / NT Sound';
            app.GridLayout6 = uigridlayout(app.CorrectNTSoundPanel);
            app.SoundSwitch = uiswitch(app.GridLayout6, 'slider');
            app.SoundSwitch.ValueChangedFcn = createCallbackFcn(app, @SoundSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myMemoryGUIApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end